classdef FedyPcdCabaiXVduasatu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FedyKlasifikasiCabai         matlab.ui.Figure
        AddDataButton                matlab.ui.control.Button
        TabelUI                      matlab.ui.control.Table
        TombolReset                  matlab.ui.control.Button
        EkstraksiWarna               matlab.ui.container.Panel
        RedLabel                     matlab.ui.control.Label
        GreenLabel                   matlab.ui.control.Label
        BlueLabel                    matlab.ui.control.Label
        redField                     matlab.ui.control.EditField
        greenField                   matlab.ui.control.EditField
        blueField                    matlab.ui.control.EditField
        EkstraksiFitur               matlab.ui.container.Panel
        AreaLabel                    matlab.ui.control.Label
        PerimeterLabel               matlab.ui.control.Label
        CenteroidLabel               matlab.ui.control.Label
        AreaField                    matlab.ui.control.EditField
        PerimeterField               matlab.ui.control.EditField
        centeroidField               matlab.ui.control.EditField
        GAMBARASALPanel              matlab.ui.container.Panel
        GambarAsli                   matlab.ui.control.UIAxes
        OpenImageButton              matlab.ui.control.Button
        HistogramButton              matlab.ui.control.Button
        SaveDataExcel                matlab.ui.control.Button
        Hasil                        matlab.ui.container.Panel
        UIAxes_2                     matlab.ui.control.UIAxes
        EditMenjadiDropDownLabel     matlab.ui.control.Label
        EditMenjadiDropDown          matlab.ui.control.DropDown
        SaveImageButton              matlab.ui.control.Button
        ProsesButton                 matlab.ui.control.Button
        SISTEMKLASIFIKASICABAILabel  matlab.ui.control.Label
    end


    properties (Access = private)
        redfunction % Description
        greenfunction % Description
        bluefunction
        areafunction
        perimeterfunction
        centeroidfunction
        isiTabel
        gambaraslicabe
        hasileditcabe
    end

    methods (Access = private)
    
       function fungsirgb(app,gambar)
            
            r_img = gambar(:,:,1);
            g_img = gambar(:,:,2);
            b_img = gambar(:,:,3);
            app.redfunction = mean2(r_img);    
            app.greenfunction = mean2(g_img);
            app.bluefunction = mean2(b_img);
            app.redField.Value = num2str(mean2(r_img));
            app.greenField.Value = num2str(mean2(g_img));
            app.blueField.Value = num2str(mean2(b_img));   
       end
        
        function eks_fitur(app,gambar)
                I = rgb2gray(gambar);
                bw = im2bw(I, graythresh(I));
                bw2 = imfill(bw,'holes');
                
                s = regionprops(bw2, 'centroid','area','perimeter');
                centroids = cat(1, s.Centroid);
                area = cat(1, s.Area);
                perimeter = cat(1, s.Perimeter);
                app.areafunction = mean2(area);
                app.perimeterfunction = mean2(perimeter);
                app.centeroidfunction = mean2(centroids);
                app.AreaField.Value = num2str(mean2(area));
                app.centeroidField.Value = num2str(mean2(centroids));
                app.PerimeterField.Value = num2str(mean2(perimeter));
        end
        
    end


    methods (Access = private)

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            [FileName,PathName] = uigetfile('*.jpeg','Select an image');
            gambar = imread(strcat(PathName,FileName));
            app.gambaraslicabe = gambar;
            setappdata(0,'parent', gambar);
            imshow(gambar, 'parent',app.GambarAsli);
            fungsirgb(app,gambar);
            eks_fitur(app,gambar);
        end

        % Value changed function: EditMenjadiDropDown
        function EditMenjadiDropDownValueChanged(app, event)
            value = app.EditMenjadiDropDown.Value;
            
        end

        % Button pushed function: ProsesButton
        function ProsesButtonPushed(app, event)
            if isempty(app.gambaraslicabe) == false
                hasil = {}
                if(app.EditMenjadiDropDown.Value == "Gambar Greyscale")
                     hasil = rgb2gray(app.gambaraslicabe);
                     imshow(hasil, 'parent', app.UIAxes_2);
                end
                if(app.EditMenjadiDropDown.Value == "Gambar Biner")
                    hasil = im2bw(app.gambaraslicabe,0.5);
                     imshow( hasil, 'parent', app.UIAxes_2);
                end
                if(app.EditMenjadiDropDown.Value == "Gambar Garis Tepi")
                    imgray = rgb2gray(app.gambaraslicabe);
                    hasil = edge(imgray,'canny');
                    imshow(hasil, 'parent', app.UIAxes_2);
                end    
                
                app.hasileditcabe = hasil;
            else 
               msgbox('Tidak ada gambar yang bisa diedit. masukkan gambar dulu!');
            end            
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
              if isempty(app.hasileditcabe) == false 
              [baseFileName, folder] = uiputfile('*.jpg', 'Specify a file');
              if baseFileName == 0
                   return ;
              end
                
              fullFileName = fullfile(folder,baseFileName);
              imwrite(app.hasileditcabe, fullFileName);  
            else 
                msgbox('Tidak ada gambar yang bisa diedit. masukkan gambar dulu!');
              end
        end

        % Button pushed function: AddDataButton
        function AddDataButtonPushed(app, event)
             if isempty(app.gambaraslicabe) == false
                prop = {'data','position'};                                                       
                attributes = get(app.TabelUI,prop);
                
                no = size(attributes{1},1) +1 ;
                data = num2str(sprintf('Data %d', no));
                r = app.redfunction;
                g = app.greenfunction;
                b = app.bluefunction;
                area = app.areafunction;
                perimeter = app.perimeterfunction;
                ceneroid = app.centeroidfunction;
                row = {no data r g b area perimeter ceneroid}
                app.TabelUI.Data = [app.isiTabel;row];
                app.isiTabel = app.TabelUI.Data;
            else 
                msgbox('Tidak ada gambar yang bisa diedit. masukkan gambar dulu!');
            end
        end

        % Button pushed function: TombolReset
        function TombolResetButtonPushed(app, event)
            app.TabelUI.Data = {};
            app.isiTabel = {};
        end

        % Button pushed function: SaveDataExcel
        function SaveDataExcelButtonPushed(app, event)
            if isempty(app.isiTabel) == false
            [baseFileName, folder] = uiputfile('*.xlsx', 'Specify a file');
                if baseFileName == 0
                    return ;
                end
                col_header = {'no','data','red','green','blue','fitur area','fitur perimeter','fitur centeroid'};
                
                fullFileName = fullfile(folder,baseFileName);
                xlswrite(fullFileName,app.TabelUI.Data,'Sheet1','A2');
                xlswrite(fullFileName,col_header,'Sheet1','A1');
            else 
                msgbox('Tidak ada data yang akan disimpan, minimal 1 data!');
            end
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)

            if isempty(app.gambaraslicabe) == false
                gambarasli = getappdata(0, 'parent');
                gray = rgb2gray(gambarasli);
                nBins = 100; % 
                [counts, x] = imhist(gray, nBins);
                bar(x, counts);
            else
                msgbox('Silahkan Import citra dulu!');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FedyKlasifikasiCabai = uifigure;
            app.FedyKlasifikasiCabai.Name = 'Fedy Klasifikasi Cabai';
            app.AddDataButton = uibutton(app.FedyKlasifikasiCabai, 'push');
            app.AddDataButton.ButtonPushedFcn = createCallbackFcn(app, @AddDataButtonPushed, true);
            app.AddDataButton.Text = 'Add To Table';
            app.TabelUI = uitable(app.FedyKlasifikasiCabai);
            app.TabelUI.ColumnName = {'No'; 'Data'; 'R'; 'G'; 'B'; 'FItur Area'; 'Fitur Perimeter'; 'Fitur Centeroid'};
            app.TabelUI.RowName = {};
            app.TombolReset = uibutton(app.FedyKlasifikasiCabai, 'push');
            app.TombolReset.ButtonPushedFcn = createCallbackFcn(app, @TombolResetButtonPushed, true);
            app.TombolReset.Text = 'Reset';
            app.EkstraksiWarna = uipanel(app.FedyKlasifikasiCabai);
            app.EkstraksiWarna.Title = 'Ekstrasi warna';
            app.RedLabel = uilabel(app.EkstraksiWarna);
            app.RedLabel.Text = 'Red';
            app.GreenLabel = uilabel(app.EkstraksiWarna);
            app.GreenLabel.Text = 'Green';
            app.BlueLabel = uilabel(app.EkstraksiWarna);
            app.BlueLabel.Text = 'Blue';
            app.redField = uieditfield(app.EkstraksiWarna, 'text');
            app.greenField = uieditfield(app.EkstraksiWarna, 'text');
            app.blueField = uieditfield(app.EkstraksiWarna, 'text');
            app.EkstraksiFitur = uipanel(app.FedyKlasifikasiCabai);
            app.EkstraksiFitur.Title = 'Ekstraksi Fitur';
            app.AreaLabel = uilabel(app.EkstraksiFitur);
            app.AreaLabel.Text = 'Area';
            app.PerimeterLabel = uilabel(app.EkstraksiFitur);
            app.PerimeterLabel.Text = 'Perimeter';
            app.CenteroidLabel = uilabel(app.EkstraksiFitur);
            app.CenteroidLabel.Text = 'Centeroid';
            app.AreaField = uieditfield(app.EkstraksiFitur, 'text');
            app.PerimeterField = uieditfield(app.EkstraksiFitur, 'text');
            app.centeroidField = uieditfield(app.EkstraksiFitur, 'text');
            app.GAMBARASALPanel = uipanel(app.FedyKlasifikasiCabai);
            app.GAMBARASALPanel.Title = 'GAMBAR ASAL';
            app.GambarAsli = uiaxes(app.GAMBARASALPanel);
            title(app.GambarAsli, 'Gambar Asli')
            app.OpenImageButton = uibutton(app.GAMBARASALPanel, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.HistogramButton = uibutton(app.GAMBARASALPanel, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.SaveDataExcel = uibutton(app.FedyKlasifikasiCabai, 'push');
            app.SaveDataExcel.ButtonPushedFcn = createCallbackFcn(app, @SaveDataExcelButtonPushed, true);
            app.SaveDataExcel.Text = 'Simpan Excel';
            app.Hasil = uipanel(app.FedyKlasifikasiCabai);
            app.Hasil.Title = 'EDIT';
            app.UIAxes_2 = uiaxes(app.Hasil);
            title(app.UIAxes_2, 'Hasil')
            app.EditMenjadiDropDownLabel = uilabel(app.Hasil);
            app.EditMenjadiDropDownLabel.Text = 'Edit Menjadi';
            app.EditMenjadiDropDown = uidropdown(app.Hasil);
            app.EditMenjadiDropDown.Items = {'Gambar Greyscale', 'Gambar Biner', 'Gambar Garis Tepi'};
            app.EditMenjadiDropDown.ValueChangedFcn = createCallbackFcn(app, @EditMenjadiDropDownValueChanged, true);
            app.EditMenjadiDropDown.Value = 'Gambar Garis Tepi';
            app.SaveImageButton = uibutton(app.Hasil, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.ProsesButton = uibutton(app.Hasil, 'push');
            app.ProsesButton.ButtonPushedFcn = createCallbackFcn(app, @ProsesButtonPushed, true);
            app.ProsesButton.Text = 'Proses';
            app.SISTEMKLASIFIKASICABAILabel = uilabel(app.FedyKlasifikasiCabai);
            app.SISTEMKLASIFIKASICABAILabel.Text = 'SISTEM KLASIFIKASI CABAI';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FedyPcdCabaiXVduasatu

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FedyKlasifikasiCabai)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FedyKlasifikasiCabai)
        end
    end
end